classdef PAMSimulatorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        PAMSimulatorAppLabel  matlab.ui.control.Label
        CreatedbyDavidCerezo03042025Label  matlab.ui.control.Label
        CloseButton           matlab.ui.control.Button
        UpdateButton          matlab.ui.control.Button
        SNREditField          matlab.ui.control.NumericEditField
        SNR_dBEditFieldLabel  matlab.ui.control.Label
        AEditField            matlab.ui.control.NumericEditField
        AEditFieldLabel       matlab.ui.control.Label
        QEditField            matlab.ui.control.NumericEditField
        QEditFieldLabel       matlab.ui.control.Label
        TEditField            matlab.ui.control.NumericEditField
        TEditFieldLabel       matlab.ui.control.Label
        NEditField            matlab.ui.control.NumericEditField
        NEditFieldLabel       matlab.ui.control.Label
        MatchedFilterAxes     matlab.ui.control.UIAxes
        NoisySignalAxes       matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
    function updatePlots(app)
        % Get parameters from UI
        N = app.NEditField.Value;
        T = app.TEditField.Value;
        Q = app.QEditField.Value;
        A = app.AEditField.Value;
        SNR_dB = app.SNREditField.Value;
    
        % Generate signals
        [r, t_r, b, y, t_y, p] = generate_pam_signal_matched_filter_output(N, T, Q, A, SNR_dB);
    
        % Plot r[n]
        plot(app.NoisySignalAxes, t_r, r, 'b'); hold(app.NoisySignalAxes, 'on');
        stem(app.NoisySignalAxes, t_r(1:Q:end), r(1:Q:end), 'ro', 'filled');
        hold(app.NoisySignalAxes, 'off');
        title(app.NoisySignalAxes, 'Received Signal r[n]');
        xlabel(app.NoisySignalAxes, 'Time (s)'); ylabel(app.NoisySignalAxes, 'Amplitude');
    
        % Plot y[n]
        plot(app.MatchedFilterAxes, t_y, y, 'm'); hold(app.MatchedFilterAxes, 'on');
        stem(app.MatchedFilterAxes, t_y(Q:Q:end), y(Q:Q:end), 'ko', 'filled');
        hold(app.MatchedFilterAxes, 'off');
        title(app.MatchedFilterAxes, 'Matched Filter Output y[n]');
        xlabel(app.MatchedFilterAxes, 'Time (s)'); ylabel(app.MatchedFilterAxes, 'Amplitude');
    end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.updatePlots();
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close all;
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NoisySignalAxes = uiaxes(app.UIFigure);
            title(app.NoisySignalAxes, 'NoisySignalAxes')
            xlabel(app.NoisySignalAxes, 'X')
            ylabel(app.NoisySignalAxes, 'Y')
            zlabel(app.NoisySignalAxes, 'Z')
            app.MatchedFilterAxes = uiaxes(app.UIFigure);
            title(app.MatchedFilterAxes, 'MatchedFilterAxes')
            xlabel(app.MatchedFilterAxes, 'X')
            ylabel(app.MatchedFilterAxes, 'Y')
            zlabel(app.MatchedFilterAxes, 'Z')
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.TEditFieldLabel = uilabel(app.UIFigure);
            app.TEditFieldLabel.Text = 'T';
            app.TEditField = uieditfield(app.UIFigure, 'numeric');
            app.QEditFieldLabel = uilabel(app.UIFigure);
            app.QEditFieldLabel.Text = 'Q';
            app.QEditField = uieditfield(app.UIFigure, 'numeric');
            app.AEditFieldLabel = uilabel(app.UIFigure);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.UIFigure, 'numeric');
            app.SNR_dBEditFieldLabel = uilabel(app.UIFigure);
            app.SNR_dBEditFieldLabel.Text = 'SNR_dB';
            app.SNREditField = uieditfield(app.UIFigure, 'numeric');
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.CreatedbyDavidCerezo03042025Label = uilabel(app.UIFigure);
            app.CreatedbyDavidCerezo03042025Label.Text = 'Created by David Cerezo, 03/04/2025';
            app.PAMSimulatorAppLabel = uilabel(app.UIFigure);
            app.PAMSimulatorAppLabel.Text = 'PAM Simulator App';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PAMSimulatorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end